classdef retoAppDesigner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        LongdeqSlider_2                matlab.ui.control.Slider
        LongdeqLabel                   matlab.ui.control.Label
        LongdeqSlider                  matlab.ui.control.Slider
        LongdeqSliderLabel             matlab.ui.control.Label
        CorrerSimulacinButton          matlab.ui.control.Button
        yEditField                     matlab.ui.control.NumericEditField
        yEditFieldLabel                matlab.ui.control.Label
        xEditField                     matlab.ui.control.NumericEditField
        xEditFieldLabel                matlab.ui.control.Label
        ConfiguracindeclulaLabel       matlab.ui.control.Label
        ConfiguracindecargasLabel      matlab.ui.control.Label
        DistanciaentrebarrasEditField  matlab.ui.control.NumericEditField
        DistanciaentrebarrasEditFieldLabel  matlab.ui.control.Label
        EstinfectadaDropDown           matlab.ui.control.DropDown
        EstinfectadaDropDownLabel      matlab.ui.control.Label
        CargasnegativasEditField       matlab.ui.control.NumericEditField
        CargasnegativasEditFieldLabel  matlab.ui.control.Label
        CargaspositivasEditField       matlab.ui.control.NumericEditField
        CargaspositivasLabel           matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = private)
        % Variables cambiables en appDesigner
        Np
        Nn
        d
        Lp
        Ln
        cell_pos % [x, y]
        cell_infected % bool
        
        % Para meshgrid y cálculos de simulación
        x
        y
        X_grid
        Y_grid
        Ex_interp
        Ey_interp
        
        % Handles gráficos
        hQuiver
        hTooltip
        hPos
        hNeg
        hCell 
    end
    
    methods (Access = private)
        
        function updatePlot(app)
            %% Parámetros fijos internos
            q   = 1e-9;    % Carga eléctrica de cada punto [C]
            N   = 30;      % Puntos en la malla
            Lim = 0.30;    % Tamaño del espacio simulado [m]
            k = 8.99e9;    % Constante de Coulomb

            %% Cálculo del Delta constante
            % Determinamos cuál lado tiene más cargas para definir el espaciado (dx)
            dx_p = (app.Np > 1) * (app.Lp / (app.Np - 1));
            dx_n = (app.Nn > 1) * (app.Ln / (app.Nn - 1));
            
            %% Posición de cada carga positiva (y = d/2)
            y_pos = app.d/2;
            x_pos = -(app.Lp/2) + (0:app.Np-1) * dx_p;
            
            %% Posición de cada carga negativa (y = -d/2)
            y_neg = -app.d/2;
            x_neg = -(app.Ln/2) + (0:app.Nn-1) * dx_n;
            
            %% Creamos la malla de puntos en 2D
            app.x = linspace(-Lim, Lim, N);
            app.y = linspace(-Lim, Lim, N);
            [app.X_grid, app.Y_grid] = meshgrid(app.x, app.y);

            %% Campo total inicializado en cero
            Ex = zeros(size(app.X_grid));
            Ey = zeros(size(app.X_grid));

            %% Sumamos el campo eléctrico de cada carga positiva
            for i = 1:app.Np
                r_pos = sqrt((app.X_grid - x_pos(i)).^2 + (app.Y_grid - y_pos).^2);
                r_pos(r_pos < 1e-10) = 1e-10; % Evitar división por cero
                Ex = Ex + k * q * (app.X_grid - x_pos(i)) ./ r_pos.^3;
                Ey = Ey + k * q * (app.Y_grid - y_pos) ./ r_pos.^3;
            end

            %% Sumamos el campo eléctrico de cada carga negativa
            for j = 1:app.Nn
                r_neg = sqrt((app.X_grid - x_neg(j)).^2 + (app.Y_grid - y_neg).^2);
                r_neg(r_neg < 1e-10) = 1e-10;
                Ex = Ex + k * (-q) * (app.X_grid - x_neg(j)) ./ r_neg.^3;
                Ey = Ey + k * (-q) * (app.Y_grid - y_neg) ./ r_neg.^3;
            end

            %% Normalización para visualización
            E_mag = sqrt(Ex.^2 + Ey.^2);
            E_mag(E_mag == 0) = 1;
            Ex_norm = Ex ./ E_mag;
            Ey_norm = Ey ./ E_mag;

            %% Pre-cálculo: convertir campo en funciones interpoladas para muestreo
            app.Ex_interp = griddedInterpolant(app.X_grid', app.Y_grid', Ex', 'linear', 'nearest');
            app.Ey_interp = griddedInterpolant(app.X_grid', app.Y_grid', Ey', 'linear', 'nearest');

            %% Configuración visual de la célula
            if app.cell_infected
                markerColor = [0.5 0 0.5];   % morado si esta infectada
            else
                markerColor = [0 1 0];       % verde si no esta infectado
            end

            %% Graficamos el campo eléctrico inicial
            % Usamos cla para borrar elementos anteriores si cambió Np o Nn
            cla(app.UIAxes);
            hold(app.UIAxes, 'on');

            app.hQuiver = quiver(app.UIAxes, app.X_grid, app.Y_grid, Ex_norm, Ey_norm, 0.5, 'b', 'LineWidth', 1.2);

            % Dibujar cargas positivas (si hay)
            if app.Np > 0
                app.hPos = plot(app.UIAxes, x_pos, repmat(y_pos, 1, app.Np), 'ro', 'MarkerSize', 8, 'MarkerFaceColor', 'r');
            end
            
            % Dibujar cargas negativas (si hay)
            if app.Nn > 0
                app.hNeg = plot(app.UIAxes, x_neg, repmat(y_neg, 1, app.Nn), 'bo', 'MarkerSize', 8, 'MarkerFaceColor', 'b');
            end

            % Graficar la célula (handle para actualización)
            app.hCell = plot(app.UIAxes, app.cell_pos(1), app.cell_pos(2), 's', 'MarkerSize', 12, ...
                 'MarkerFaceColor', markerColor, 'MarkerEdgeColor', 'k', 'LineWidth', 1.2);

            % Etiquetas
            title(app.UIAxes, sprintf('Campo eléctrico dinámico | Np = %d, Nn = %d, d = %.2f m', app.Np, app.Nn, app.d), 'FontSize', 13);
            xlabel(app.UIAxes, 'x [m]'); 
            ylabel(app.UIAxes, 'y [m]');
            
            % Asegurar límites constantes
            axis(app.UIAxes, 'equal'); 
            axis(app.UIAxes, [-Lim Lim -Lim Lim]);
            grid(app.UIAxes, 'on');
            
            hold(app.UIAxes, 'off');

            grid(app.UIAxes, 'on');
            
            % --- AÑADIR CÓDIGO DEL TOOLTIP AQUÍ ---
            % Borrar el tooltip anterior si existe para evitar duplicados
            if isprop(app, 'hTooltip')
                delete(app.hTooltip);
            end
            
            % Crear un objeto de texto flotante oculto por defecto
            app.hTooltip = text(app.UIAxes, 0, 0, '', ...
                'BackgroundColor', [0.15 0.15 0.15], ... % Fondo gris oscuro
                'Color', 'white', ...                    % Texto blanco
                'EdgeColor', [0.5 0.5 0.5], ...          % Borde gris
                'FontSize', 10, ...
                'Margin', 4, ...
                'FontName', 'Consolas', ...              % Fuente monoespaciada para alinear números
                'Visible', 'off', ...
                'PickableParts', 'none');                % Evita que interfiera con los clics
            % --------------------------------------

            hold(app.UIAxes, 'off');
        end
        
        function UIFigureWindowMouseMotion(app)
            cp = app.UIAxes.CurrentPoint;
            x_mouse = cp(1, 1);
            y_mouse = cp(1, 2);
            
            % Obtener los límites actuales del gráfico
            x_lim = app.UIAxes.XLim;
            y_lim = app.UIAxes.YLim;
            
            % Comprobar si el ratón está dentro del gráfico (UIAxes)
            if x_mouse >= x_lim(1) && x_mouse <= x_lim(2) && ...
               y_mouse >= y_lim(1) && y_mouse <= y_lim(2)
                
                % Asegurarnos de que el campo ya está calculado
                if ~isempty(app.Ex_interp) && ~isempty(app.Ey_interp)
                    
                    % Calcular campo eléctrico en la posición del ratón
                    Ex_val = app.Ex_interp(x_mouse, y_mouse);
                    Ey_val = app.Ey_interp(x_mouse, y_mouse);
                    
                    % Formatear el texto (similar a tu imagen de referencia)
                    hoverText = {sprintf('[X,Y] [%.6f  %.6f]', x_mouse, y_mouse), ...
                                 sprintf('[U,V] [%.6e  %.6e]', Ex_val, Ey_val)};
                             
                    % Actualizar la posición del texto (ligeramente desplazado para que el cursor no lo tape)
                    offset = (x_lim(2) - x_lim(1)) * 0.02;
                    
                    if isvalid(app.hTooltip)
                        app.hTooltip.Position = [x_mouse + offset, y_mouse + offset, 0];
                        app.hTooltip.String = hoverText;
                        app.hTooltip.Visible = 'on'; % Mostrar el tooltip
                    end
                end
            else
                % Ocultar el tooltip si el ratón sale del área del gráfico
                if isprop(app, 'hTooltip') && isvalid(app.hTooltip)
                    app.hTooltip.Visible = 'off';
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Visible = 'off';
            
            % Inicialización de variables (desde el script original)
            app.Np = 50;      
            app.Nn = 20;      
            app.d  = 0.15;    
            app.Lp  = 0.50;
            app.Ln  = 0.20;

            app.cell_pos = [0.0, 0.0];   
            app.cell_infected = true;
            
            % Esto creará el gráfico de fondo de forma segura
            app.updatePlot();
            
            app.UIFigure.Visible = 'on';
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            % Si se hace clic en el eje, podríamos querer resetear a la configuración inicial
            app.updatePlot();
        end

        % Value changed function: CargaspositivasEditField
        function CargaspositivasEditFieldValueChanged(app, event)
            app.Np = app.CargaspositivasEditField.Value;
        end

        % Button pushed function: CorrerSimulacinButton
        function CorrerSimulacinButtonPushed(app, event)
            app.updatePlot();
            
            %% Parámetros de la simulación
            q = 1e-9;
            Lim = 0.30;
            cell_charge_mag = q; 
            
            % Asignar carga según el estado de infección
            if app.cell_infected
                q_cell = +cell_charge_mag;
            else
                q_cell = -cell_charge_mag;
            end
            
            % Dinámica: parámetros del tiempo
            dt = 0.005;         % paso de tiempo [s]
            T_total = 500;      % tiempo total de simulación [s]
            nSteps = ceil(T_total / dt);
            
            % Estado dinámico de la célula
            cell_pos_curr = app.cell_pos;
            cell_vel = [0, 0];
            
            mobility = 1e-6;       % movilidad efectiva [m^2/(N·s)]
            v_max = 1e-2;          % velocidad máxima permitida [m/s]
            alpha = 0.2;           % suavizado exponencial
            
            %% Bucle de la Simulación
            for t = 1:nSteps
                % Obtener campo en la posición actual de la célula (usando variables ya interpoladas en updatePlot)
                Ex_loc = app.Ex_interp(cell_pos_curr(1), cell_pos_curr(2));
                Ey_loc = app.Ey_interp(cell_pos_curr(1), cell_pos_curr(2));
                E_loc = [Ex_loc, Ey_loc];
                E_norm = norm(E_loc);
                
                if E_norm > 0
                    % Dirección del campo
                    v_desired = mobility * q_cell * E_loc / abs(q_cell); 
                    % Escalar magnitud proporcional a |E|
                    v_desired = v_desired * (E_norm / max(E_norm,1)); 
                else
                    v_desired = [0,0];
                end
                
                % Limitar velocidad deseada
                v_desired_norm = norm(v_desired);
                if v_desired_norm > v_max
                    v_desired = v_desired * (v_max / v_desired_norm);
                end
                
                % Suavizar la transición de velocidad para seguir líneas de campo de forma continua
                cell_vel = (1 - alpha) * cell_vel + alpha * v_desired;
                
                % Actualizar posición según la velocidad suavizada
                cell_pos_curr = cell_pos_curr + cell_vel * dt;
                
                % Mantener dentro de la ventana
                cell_pos_curr(1) = max(min(cell_pos_curr(1), Lim), -Lim);
                cell_pos_curr(2) = max(min(cell_pos_curr(2), Lim), -Lim);
                
                % Evitar atravesar exactamente la línea de carga: posicionar sobre la línea
                % (Simulando una barrera física solo en la longitud de las barras)
                % Evitar atravesar exactamente la línea de carga: posicionar sobre la línea
                y_upper = app.d/2;
                y_lower = -app.d/2;
                
                % Comprobar si la partícula está dentro de los límites en X de cada barrera
                within_upper_x = (cell_pos_curr(1) >= -app.Lp/2) && (cell_pos_curr(1) <= app.Lp/2);
                within_lower_x = (cell_pos_curr(1) >= -app.Ln/2) && (cell_pos_curr(1) <= app.Ln/2);
                
                % Aplicar la restricción Y y DETENER LA SIMULACIÓN si choca
                if cell_pos_curr(2) >= y_upper && within_upper_x
                    cell_pos_curr(2) = y_upper;
                    cell_vel = [0, 0];
                    
                    % Actualizamos la posición final en la interfaz
                    if isvalid(app.hCell)
                        app.hCell.XData = cell_pos_curr(1);
                        app.hCell.YData = cell_pos_curr(2);
                    end
                    drawnow;
                    break; % <--- Esto termina el bucle 'for', deteniendo la célula por completo
                    
                elseif cell_pos_curr(2) <= y_lower && within_lower_x
                    cell_pos_curr(2) = y_lower;
                    cell_vel = [0, 0];
                    
                    % Actualizamos la posición final en la interfaz
                    if isvalid(app.hCell)
                        app.hCell.XData = cell_pos_curr(1);
                        app.hCell.YData = cell_pos_curr(2);
                    end
                    drawnow;
                    break; % <--- Esto termina el bucle 'for', deteniendo la célula por completo
                end
                
                % Actualizar el gráfico de la célula utilizando el handle (si no ha chocado)
                if isvalid(app.hCell)
                    app.hCell.XData = cell_pos_curr(1);
                    app.hCell.YData = cell_pos_curr(2);
                end
                
                drawnow;
            end
        end

        % Value changed function: CargasnegativasEditField
        function CargasnegativasEditFieldValueChanged(app, event)
            app.Nn = app.CargasnegativasEditField.Value; 
        end

        % Value changed function: DistanciaentrebarrasEditField
        function DistanciaentrebarrasEditFieldValueChanged(app, event)
            app.d= app.DistanciaentrebarrasEditField.Value;
        end

        % Value changed function: xEditField
        function xEditFieldValueChanged(app, event)
            app.cell_pos(1) = app.xEditField.Value;
            
        end

        % Value changed function: yEditField
        function yEditFieldValueChanged(app, event)
            app.cell_pos(2) = app.yEditField.Value;
        end

        % Value changed function: EstinfectadaDropDown
        function EstinfectadaDropDownValueChanged(app, event)
            value = app.EstinfectadaDropDown.Value;
            % Corregido el return por asignación de variable de clase
            if (value == "Si")
                app.cell_infected = true;
            elseif (value == "No")
                app.cell_infected = false;
            end
        end

        % Callback function
        function LongdeqSliderValueChanged(app, event)

        end

        % Callback function
        function LongdeqSlider_2ValueChanged(app, event)

        end

        % Value changed function: LongdeqSlider
        function LongdeqSliderValueChanged2(app, event)
            app.Lp = app.LongdeqSlider.Value;
        end

        % Value changed function: LongdeqSlider_2
        function LongdeqSlider_2ValueChanged2(app, event)
            app.Ln= app.LongdeqSlider_2.Value;
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            app.UIFigureWindowMouseMotion();
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {237, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.CargaspositivasLabel = uilabel(app.LeftPanel);
            app.CargaspositivasLabel.Text = {'Cargas'; 'positivas'};
            app.CargaspositivasEditField = uieditfield(app.LeftPanel, 'numeric');
            app.CargaspositivasEditField.ValueChangedFcn = createCallbackFcn(app, @CargaspositivasEditFieldValueChanged, true);
            app.CargaspositivasEditField.Value = 50;
            app.CargasnegativasEditFieldLabel = uilabel(app.LeftPanel);
            app.CargasnegativasEditFieldLabel.Text = {'Cargas'; 'negativas'};
            app.CargasnegativasEditField = uieditfield(app.LeftPanel, 'numeric');
            app.CargasnegativasEditField.ValueChangedFcn = createCallbackFcn(app, @CargasnegativasEditFieldValueChanged, true);
            app.CargasnegativasEditField.Value = 20;
            app.EstinfectadaDropDownLabel = uilabel(app.LeftPanel);
            app.EstinfectadaDropDownLabel.Text = {'¿Está '; 'infectada?'};
            app.EstinfectadaDropDown = uidropdown(app.LeftPanel);
            app.EstinfectadaDropDown.Items = {'Si', 'No'};
            app.EstinfectadaDropDown.ValueChangedFcn = createCallbackFcn(app, @EstinfectadaDropDownValueChanged, true);
            app.EstinfectadaDropDown.Value = 'Si';
            app.DistanciaentrebarrasEditFieldLabel = uilabel(app.LeftPanel);
            app.DistanciaentrebarrasEditFieldLabel.Text = {'Distancia'; 'entre barras'};
            app.DistanciaentrebarrasEditField = uieditfield(app.LeftPanel, 'numeric');
            app.DistanciaentrebarrasEditField.Limits = [-0.5 0.5];
            app.DistanciaentrebarrasEditField.ValueChangedFcn = createCallbackFcn(app, @DistanciaentrebarrasEditFieldValueChanged, true);
            app.DistanciaentrebarrasEditField.Value = 0.15;
            app.ConfiguracindecargasLabel = uilabel(app.LeftPanel);
            app.ConfiguracindecargasLabel.Text = 'Configuración de cargas';
            app.ConfiguracindeclulaLabel = uilabel(app.LeftPanel);
            app.ConfiguracindeclulaLabel.Text = 'Configuración de célula';
            app.xEditFieldLabel = uilabel(app.LeftPanel);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.LeftPanel, 'numeric');
            app.xEditField.ValueChangedFcn = createCallbackFcn(app, @xEditFieldValueChanged, true);
            app.yEditFieldLabel = uilabel(app.LeftPanel);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.LeftPanel, 'numeric');
            app.yEditField.ValueChangedFcn = createCallbackFcn(app, @yEditFieldValueChanged, true);
            app.CorrerSimulacinButton = uibutton(app.LeftPanel, 'push');
            app.CorrerSimulacinButton.ButtonPushedFcn = createCallbackFcn(app, @CorrerSimulacinButtonPushed, true);
            app.CorrerSimulacinButton.Text = 'Correr Simulación';
            app.LongdeqSliderLabel = uilabel(app.LeftPanel);
            app.LongdeqSliderLabel.Text = {'Long de'; '+q'};
            app.LongdeqSlider = uislider(app.LeftPanel);
            app.LongdeqSlider.Limits = [0 0.5];
            app.LongdeqSlider.ValueChangedFcn = createCallbackFcn(app, @LongdeqSliderValueChanged2, true);
            app.LongdeqSlider.Value = 0.5;
            app.LongdeqLabel = uilabel(app.LeftPanel);
            app.LongdeqLabel.Text = {'Long de'; '-q'};
            app.LongdeqSlider_2 = uislider(app.LeftPanel);
            app.LongdeqSlider_2.Limits = [0 0.5];
            app.LongdeqSlider_2.ValueChangedFcn = createCallbackFcn(app, @LongdeqSlider_2ValueChanged2, true);
            app.LongdeqSlider_2.Value = 0.2;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Dielectroforésis')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = retoAppDesigner

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end